classdef Equalizer_purple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DownPanel               matlab.ui.container.Panel
        DesignedByQiuLabel      matlab.ui.control.Label
        SpectrumPanel           matlab.ui.container.Panel
        ProcessedSpectrum       matlab.ui.control.UIAxes
        OriginSpectrum          matlab.ui.control.UIAxes
        Lamp                    matlab.ui.control.Lamp
        KnobPanel               matlab.ui.container.Panel
        Q4EditField             matlab.ui.control.NumericEditField
        Q3EditField             matlab.ui.control.NumericEditField
        Q2EditField             matlab.ui.control.NumericEditField
        Gain5EditField          matlab.ui.control.NumericEditField
        Gain4EditField          matlab.ui.control.NumericEditField
        Gain3EditField          matlab.ui.control.NumericEditField
        Gain2EditField          matlab.ui.control.NumericEditField
        Gain1EditField          matlab.ui.control.NumericEditField
        F5EditField             matlab.ui.control.NumericEditField
        F4EditField             matlab.ui.control.NumericEditField
        F3EditField             matlab.ui.control.NumericEditField
        F2EditField             matlab.ui.control.NumericEditField
        F1EditField             matlab.ui.control.NumericEditField
        HighShelfLabel          matlab.ui.control.Label
        PeakNotchLabel          matlab.ui.control.Label
        LowShelfLabel           matlab.ui.control.Label
        F5Knob                  matlab.ui.control.Knob
        F4Knob                  matlab.ui.control.Knob
        F3Knob                  matlab.ui.control.Knob
        F2Knob                  matlab.ui.control.Knob
        F1Knob                  matlab.ui.control.Knob
        Q4Knob                  matlab.ui.control.Knob
        Q3Knob                  matlab.ui.control.Knob
        Q2Knob                  matlab.ui.control.Knob
        Gain5Knob               matlab.ui.control.Knob
        Gain4Knob               matlab.ui.control.Knob
        Gain3Knob               matlab.ui.control.Knob
        Gain2Knob               matlab.ui.control.Knob
        Gain1Knob               matlab.ui.control.Knob
        QLabel                  matlab.ui.control.Label
        GaindBLabel             matlab.ui.control.Label
        FrequencyHzLabel        matlab.ui.control.Label
        UpPanel                 matlab.ui.container.Panel
        PresetsDropDown         matlab.ui.control.DropDown
        Panel                   matlab.ui.container.Panel
        FilenameEditField       matlab.ui.control.EditField
        ExportAudioButton       matlab.ui.control.Button
        ResponseRecoveryButton  matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        PlaybackButton          matlab.ui.control.Button
        SelectAudioFileButton   matlab.ui.control.Button
        EQPlot                  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        autoUpdate
    end

    methods (Access = private)

        function [a, b] = PeakFilter_Designer(~, F, Q, G, sr)

            Wc = 2*F / sr;  V0 = 10^(G/20);  K = tan(pi*Wc/2);

            b2 = (K^2-(V0/Q)*K+1) / (K^2+K/Q+1);  b1 = 2*(K^2-1) / (K^2+K/Q+1);  
            b0 = (K^2+K*V0/Q+1) / (K^2+K/Q+1);
            a1 = 2*(K^2-1) / (K^2+K/Q+1);  a2 = (K^2-K/Q+1) / (K^2+K/Q+1);

            b = [b0, b1, b2];  a = [1, a1, a2];

        end


        function [a, b] = LowShelfFilter_Designer(~, f, G, sr)

            Wc = f*2/sr;  V0 = 10^(G/20);  K = tan(pi*Wc/2);

            b2 = (V0*K^2-sqrt(2*V0)*K+1) / (K^2+sqrt(2)*K+1);  b1 = 2*(V0*K^2-1) / (K^2+sqrt(2)*K+1);  
            b0 = (V0*K^2+sqrt(2*V0)*K+1) / (K^2+sqrt(2)*K+1);
            a1 = 2*(K^2 -1) / (K^2 + sqrt(2)*K + 1);  a2 = (K^2 - sqrt(2)*K + 1) / (K^2 + sqrt(2)*K + 1);

            b = [b0, b1, b2];  a = [1, a1, a2];

        end

        function [a, b] = HighShelfFilter_Designer(~, f, G, sr)

            Wc = f*2/sr;  V0 = 10^(G/20);  K = tan(pi*Wc/2);

            b2 = (K^2 - sqrt(2*V0)*K + V0) / (K^2 + sqrt(2)*K + 1);  b1 = 2*(K^2 -V0) / (K^2 + sqrt(2)*K + 1);  
            b0 = (K^2 + sqrt(2*V0)*K + V0) / (K^2 + sqrt(2)*K + 1);
            a1 = 2*(K^2 -1) / (K^2 + sqrt(2)*K + 1);  a2 = (K^2 - sqrt(2)*K + 1) / (K^2 + sqrt(2)*K + 1);

            b = [b0, b1, b2];  a = [1, a1, a2];

        end


        function [AmplitudeFrequencyResponse, FrequencyAxis] = generateFrequencyResponse(app, BufferSize)

            F1 = app.F1Knob.Value; F5 = app.F5Knob.Value;
            F2 = app.F2Knob.Value; F3 = app.F3Knob.Value; F4 = app.F4Knob.Value;

            Gain1 = app.Gain1Knob.Value; Gain5 = app.Gain5Knob.Value;
            Gain2 = app.Gain2Knob.Value; Gain3 = app.Gain3Knob.Value; Gain4 = app.Gain4Knob.Value;

            Q2 = app.Q2Knob.Value; Q3 = app.Q3Knob.Value; Q4 = app.Q4Knob.Value;

            sr = app.SelectAudioFileButton.UserData;

            try
                [a1, b1] = LowShelfFilter_Designer(app, F1, Gain1, sr);
                [a5, b5] = HighShelfFilter_Designer(app, F5, Gain5, sr);

                [a2, b2] = PeakFilter_Designer(app, F2, Q2, Gain2, sr);
                [a3, b3] = PeakFilter_Designer(app, F3, Q3, Gain3, sr);
                [a4, b4] = PeakFilter_Designer(app, F4, Q4, Gain4, sr);
            catch
                errordlg("Haven't select any audio file yet!", "ERROR")
                return
            end
           
            H1 = freqz(b1, a1, BufferSize, 'half', sr);
            H2 = freqz(b2, a2, BufferSize, 'half', sr);
            H3 = freqz(b3, a3, BufferSize, 'half', sr);
            H4 = freqz(b4, a4, BufferSize, 'half', sr);
            H5 = freqz(b5, a5, BufferSize, 'half', sr);

            AmplitudeFrequencyResponse = H1 .* H2 .* H3 .* H4 .* H5;
            FrequencyAxis = linspace(sr/BufferSize, sr/2, BufferSize);

        end


        function updatePlot(app)

            [H, f] = generateFrequencyResponse(app, 2048);
            H = 20 * log10(H);

            f_interp = linspace(0, 20000, 20000);
            H_interp = spline(f, H, f_interp);
            warning("off");  
            plot(app.EQPlot, f_interp, H_interp);  xlabel(app.EQPlot, 'Frequency(Hz)');  ylabel(app.EQPlot, 'Gain(dB)');
    
        end            


        function meshPlot(app, s, f, t, num)

            clims = [-200 0];
            if num == 1
                 imagesc(app.OriginSpectrum, t, f, mag2db(abs(s) .^2), clims); 
                 set(app.OriginSpectrum, 'YDir', 'normal')
                 colormap(app.OriginSpectrum, cool(512)); colorbar(app.OriginSpectrum)
                 xlabel(app.OriginSpectrum, "Time"); ylabel(app.OriginSpectrum, "Frequency") 
            else
                 imagesc(app.ProcessedSpectrum, t, f, mag2db(abs(s) .^2), clims); 
                 set(app.ProcessedSpectrum, 'YDir', 'normal')
                 colormap(app.ProcessedSpectrum, cool(512)); colorbar(app.ProcessedSpectrum)
                 xlabel(app.ProcessedSpectrum, "Time"); ylabel(app.ProcessedSpectrum, "Frequency")
            end
            
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.Gain1Knob.Value = 0; app.Gain1EditField.Value = 0;
            app.Gain2Knob.Value = 0; app.Gain2EditField.Value = 0;
            app.Gain3Knob.Value = 0; app.Gain3EditField.Value = 0;
            app.Gain4Knob.Value = 0; app.Gain4EditField.Value = 0;
            app.Gain5Knob.Value = 0; app.Gain5EditField.Value = 0;

            app.Q2Knob.Value = 2; app.Q2EditField.Value = 2;
            app.Q3Knob.Value = 2; app.Q3EditField.Value = 2; 
            app.Q4Knob.Value = 2; app.Q4EditField.Value = 2;

            app.F1Knob.Value = 200; app.F1EditField.Value = 200;
            app.F2Knob.Value = 5000; app.F2EditField.Value = 5000;
            app.F3Knob.Value = 8000; app.F3EditField.Value = 8000;
            app.F4Knob.Value = 12000; app.F4EditField.Value = 12000;
            app.F5Knob.Value = 14000; app.F5EditField.Value = 14000;
 
        end

        % Value changed function: PresetsDropDown
        function PresetsDropDownValueChanged(app, event)
            
            value = app.PresetsDropDown.Value;
     
            if strcmpi(value, 'Low Cut')
                app.F1Knob.Value = 1000; app.F1EditField.Value = 1000; app.Gain1Knob.Value = -30; app.Gain1EditField.Value = -30; 
                app.F2Knob.Value = 500; app.F2EditField.Value = 500; app.Gain2Knob.Value = -18; app.Gain2EditField.Value = -18; app.Q2Knob.Value = 2; app.Q2EditField.Value = 2;
                app.F3Knob.Value = 8000; app.F3EditField.Value = 8000; app.Gain3Knob.Value = 0; app.Gain3EditField.Value = 0; app.Q3Knob.Value = 2; app.Q3EditField.Value = 2;
                app.F4Knob.Value = 12000; app.F4EditField.Value = 12000; app.Gain4Knob.Value = 0; app.Gain4EditField.Value = 0; app.Q4Knob.Value = 2; app.Q4EditField.Value = 2;
                app.F5Knob.Value = 14000; app.F5EditField.Value = 14000; app.Gain5Knob.Value = 0; app.Gain5EditField.Value = 0;
                updatePlot(app);
                app.Lamp.Color = [0.15 0.15 0.15];

            elseif strcmpi(value, 'High Cut')
                app.F1Knob.Value = 200; app.F1EditField.Value = 200; app.Gain1Knob.Value = 0; app.Gain1EditField.Value = 0;
                app.F2Knob.Value = 5000; app.F2EditField.Value = 5000; app.Gain2Knob.Value = 0; app.Gain2EditField.Value = 0; app.Q2Knob.Value = 2; app.Q2EditField.Value = 2;
                app.F3Knob.Value = 8000; app.F3EditField.Value = 8000; app.Gain3Knob.Value = 0; app.Gain3EditField.Value = 0; app.Q3Knob.Value = 2; app.Q3EditField.Value = 2;
                app.F4Knob.Value = 9000; app.F4EditField.Value = 9000; app.Gain4Knob.Value = -18; app.Gain4EditField.Value = -18; app.Q4Knob.Value = 0.35; app.Q4EditField.Value = 0.35;
                app.F5Knob.Value = 8800; app.F5EditField.Value = 8800; app.Gain5Knob.Value = -30; app.Gain5EditField.Value = -30;
                updatePlot(app);
                app.Lamp.Color = [0.15 0.15 0.15];

            elseif strcmpi(value, 'Band Pass')
                app.F1Knob.Value = 1000; app.F1EditField.Value = 1000; app.Gain1Knob.Value = -30; app.Gain1EditField.Value = -30;
                app.F2Knob.Value = 5000; app.F2EditField.Value = 5000; app.Gain2Knob.Value = 0; app.Gain2EditField.Value = 0; app.Q2Knob.Value = 2; app.Q2EditField.Value = 2;
                app.F3Knob.Value = 1000; app.F3EditField.Value = 1000; app.Gain3Knob.Value = 6; app.Gain3EditField.Value = 6; app.Q3Knob.Value = 2.5; app.Q3EditField.Value = 2.5;
                app.F4Knob.Value = 12000; app.F4EditField.Value = 12000; app.Gain4Knob.Value = 0; app.Gain4EditField.Value = 0; app.Q4Knob.Value = 2; app.Q4EditField.Value = 2;
                app.F5Knob.Value = 1000; app.F5EditField.Value = 1000; app.Gain5Knob.Value = -30; app.Gain5EditField.Value = -30;
                updatePlot(app);
                app.Lamp.Color = [0.15 0.15 0.15];

            elseif strcmpi(value, 'Band Stop')
                app.F1Knob.Value = 200; app.F1EditField.Value = 200; app.Gain1Knob.Value = 0; app.Gain1EditField.Value = 0;
                app.F2Knob.Value = 5000; app.F2EditField.Value = 5000; app.Gain2Knob.Value = 0; app.Gain2EditField.Value = 0; app.Q2Knob.Value = 2; app.Q2EditField.Value = 2;
                app.F3Knob.Value = 1000; app.F3EditField.Value = 1000; app.Gain3Knob.Value = -18; app.Gain3EditField.Value = -18; app.Q3Knob.Value = 0.35; app.Q3EditField.Value = 0.35;
                app.F4Knob.Value = 12000; app.F4EditField.Value = 12000; app.Gain4Knob.Value = 0; app.Gain4EditField.Value = 0; app.Q4Knob.Value = 2; app.Q4EditField.Value = 2;
                app.F5Knob.Value = 14000; app.F5EditField.Value = 14000; app.Gain5Knob.Value = 0; app.Gain5EditField.Value = 0;
                updatePlot(app);
                app.Lamp.Color = [0.15 0.15 0.15];

            elseif strcmpi(value, 'Drums')
                app.F1Knob.Value = 25; app.F1EditField.Value = 25; app.Gain1Knob.Value = -30; app.Gain1EditField.Value = -30;
                app.F2Knob.Value = 100; app.F2EditField.Value = 100; app.Gain2Knob.Value = 2; app.Gain2EditField.Value = 2; app.Q2Knob.Value = 1.0; app.Q2EditField.Value = 1.0;
                app.F3Knob.Value = 50; app.F3EditField.Value = 50; app.Gain3Knob.Value = -1.0; app.Gain3EditField.Value = -1.0; app.Q3Knob.Value = 0.3; app.Q3EditField.Value = 0.3;
                app.F4Knob.Value = 12000; app.F4EditField.Value = 12000; app.Gain4Knob.Value = 0; app.Gain4EditField.Value = 0; app.Q4Knob.Value = 2; app.Q4EditField.Value = 2;
                app.F5Knob.Value = 10000; app.F5EditField.Value = 10000; app.Gain5Knob.Value = 1; app.Gain5EditField.Value = 1;
                updatePlot(app);
                app.Lamp.Color = [0.15 0.15 0.15];

            elseif strcmpi(value, 'Phone')
                app.F1Knob.Value = 520; app.F1EditField.Value = 520; app.Gain1Knob.Value = -30; app.Gain1EditField.Value = -30;
                app.F2Knob.Value = 941.7; app.F2EditField.Value = 941.7; app.Gain2Knob.Value = 4.65; app.Gain2EditField.Value = 4.65; app.Q2Knob.Value = 3.773; app.Q2EditField.Value = 3.773;
                app.F3Knob.Value = 1609.7; app.F3EditField.Value = 1609.7; app.Gain3Knob.Value = 6.41; app.Gain3EditField.Value = 6.41; app.Q3Knob.Value = 2.809; app.Q3EditField.Value = 2.809;
                app.F4Knob.Value = 2656.9; app.F4EditField.Value = 2656.9; app.Gain4Knob.Value = 6.03; app.Gain4EditField.Value = 6.03; app.Q4Knob.Value = 6.325; app.Q4EditField.Value = 6.325;
                app.F5Knob.Value = 10000; app.F5EditField.Value = 10000; app.Gain5Knob.Value = 1; app.Gain5EditField.Value = 1;
                updatePlot(app);
                app.Lamp.Color = [0.15 0.15 0.15];
               
            elseif strcmpi(value, 'Vocal')
                app.F1Knob.Value = 30; app.F1EditField.Value = 30; app.Gain1Knob.Value = -30; app.Gain1EditField.Value = -30;
                app.F2Knob.Value = 60; app.F2EditField.Value = 60; app.Gain2Knob.Value = -3; app.Gain2EditField.Value = -3; app.Q2Knob.Value = 0.3; app.Q2EditField.Value = 0.3;
                app.F3Knob.Value = 8000; app.F3EditField.Value = 8000; app.Gain3Knob.Value = 0; app.Gain3EditField.Value = 0; app.Q3Knob.Value = 2; app.Q3EditField.Value = 2;
                app.F4Knob.Value = 6500; app.F4EditField.Value = 6500; app.Gain4Knob.Value = 1; app.Gain4EditField.Value = 1; app.Q4Knob.Value = 1; app.Q4EditField.Value = 1;
                app.F5Knob.Value = 10000; app.F5EditField.Value = 10000; app.Gain5Knob.Value = 3; app.Gain5EditField.Value = 3;
                updatePlot(app);
                app.Lamp.Color = [0.15 0.15 0.15];

            elseif strcmpi(value, 'Tilt Shelf')
                app.F1Knob.Value = 200; app.F1EditField.Value = 200; app.Gain1Knob.Value = -4.5; app.Gain1EditField.Value = -4.5;
                app.F2Knob.Value = 5000; app.F2EditField.Value = 5000; app.Gain2Knob.Value = 0; app.Gain2EditField.Value = 0; app.Q2Knob.Value = 2; app.Q2EditField.Value = 2;
                app.F3Knob.Value = 8000; app.F3EditField.Value = 8000; app.Gain3Knob.Value = 0; app.Gain3EditField.Value = 0; app.Q3Knob.Value = 2; app.Q3EditField.Value = 2;
                app.F4Knob.Value = 12000; app.F4EditField.Value = 12000; app.Gain4Knob.Value = 0; app.Gain4EditField.Value = 0; app.Q4Knob.Value = 2; app.Q4EditField.Value = 2;
                app.F5Knob.Value = 200; app.F5EditField.Value = 200; app.Gain5Knob.Value = 4.5; app.Gain5EditField.Value = 4.5;
                updatePlot(app);
                app.Lamp.Color = [0.15 0.15 0.15];

            elseif strcmpi(value, 'Front Enhance(Shu-Nung)')
                app.F1Knob.Value = 200; app.F1EditField.Value = 200; app.Gain1Knob.Value = 0; app.Gain1EditField.Value = 0;
                app.F2Knob.Value = 4000; app.F2EditField.Value = 4000; app.Gain2Knob.Value = 10; app.Gain2EditField.Value = 10; app.Q2Knob.Value = 5.76; app.Q2EditField.Value = 5.76;
                app.F3Knob.Value = 7500; app.F3EditField.Value = 7500; app.Gain3Knob.Value = -10; app.Gain3EditField.Value = -10; app.Q3Knob.Value = 1.41; app.Q3EditField.Value = 1.41;
                app.F4Knob.Value = 14000; app.F4EditField.Value = 14000; app.Gain4Knob.Value = 10; app.Gain4EditField.Value = 10; app.Q4Knob.Value = 5.76; app.Q4EditField.Value = 5.76;
                app.F5Knob.Value = 14000; app.F5EditField.Value = 14000; app.Gain5Knob.Value = 0; app.Gain5EditField.Value = 0;
                updatePlot(app);
                app.Lamp.Color = [0.15 0.15 0.15];

            elseif strcmpi(value, 'Above Enhance(Shu-Nung)')
                app.F1Knob.Value = 200; app.F1EditField.Value = 200; app.Gain1Knob.Value = 0; app.Gain1EditField.Value = 0;
                app.F2Knob.Value = 4000; app.F2EditField.Value = 4000; app.Gain2Knob.Value = 10; app.Gain2EditField.Value = 10; app.Q2Knob.Value = 5.76; app.Q2EditField.Value = 5.76;
                app.F3Knob.Value = 8000; app.F3EditField.Value = 8000; app.Gain3Knob.Value = 10; app.Gain3EditField.Value = 10; app.Q3Knob.Value = 5.76; app.Q3EditField.Value = 5.76;
                app.F4Knob.Value = 12000; app.F4EditField.Value = 12000; app.Gain4Knob.Value = 0; app.Gain4EditField.Value = 0; app.Q4Knob.Value = 2; app.Q4EditField.Value = 2;
                app.F5Knob.Value = 14000; app.F5EditField.Value = 14000; app.Gain5Knob.Value = 0; app.Gain5EditField.Value = 0;
                updatePlot(app);
                app.Lamp.Color = [0.15 0.15 0.15];
        
            elseif strcmpi(value, 'Front Enhance(Song)')
                app.F1Knob.Value = 200; app.F1EditField.Value = 200; app.Gain1Knob.Value = 0; app.Gain1EditField.Value = 0;
                app.F2Knob.Value = 4000; app.F2EditField.Value = 4000; app.Gain2Knob.Value = 10; app.Gain2EditField.Value = 10; app.Q2Knob.Value = 4.31; app.Q2EditField.Value = 4.31;
                app.F3Knob.Value = 7000; app.F3EditField.Value = 7000; app.Gain3Knob.Value = -10; app.Gain3EditField.Value = -10; app.Q3Knob.Value = 1.41; app.Q3EditField.Value = 1.41;
                app.F4Knob.Value = 14000; app.F4EditField.Value = 14000; app.Gain4Knob.Value = 10; app.Gain4EditField.Value = 10; app.Q4Knob.Value = 5.76; app.Q4EditField.Value = 5.76;
                app.F5Knob.Value = 14000; app.F5EditField.Value = 14000; app.Gain5Knob.Value = 0; app.Gain5EditField.Value = 0;
                updatePlot(app);
                app.Lamp.Color = [0.15 0.15 0.15];


            elseif strcmpi(value, '<-Select Presets->')
                startupFcn(app)
                updatePlot(app)
                app.Lamp.Color = [0.3 0.04 0.5];
 
            end

        end

        % Button pushed function: SelectAudioFileButton
        function SelectAudioFileButtonPushed(app, event)
            
            [file,path] = uigetfile({'*.wav';'*.mp3';'*.*'});
            if isequal(file, 0)
               errordlg("Didn't select any effective file","ERROR")
            else
               cla(app.OriginSpectrum, app.ProcessedSpectrum)
               filepath = strcat(path,file);
               afr = dsp.AudioFileReader(filepath);
               app.SelectAudioFileButton.UserData = afr.SampleRate;
               app.PlaybackButton.UserData = filepath;
               app.FilenameEditField.Value = file;
               app.FilenameEditField.FontColor = [0, 1, 0];

               [y, sr] = audioread(filepath); y = y(:, 1);

               [s, f, t] = stft(y, sr, 'Window', gausswin(2048), 'OverlapLength', 512, 'FFTLength', 2048, 'FrequencyRange', 'onesided');
               meshPlot(app, s, f, t, 1);

               updatePlot(app)
               app.Lamp.Color = [0.15 0.15 0.15];
            end

        end

        % Button pushed function: PlaybackButton
        function PlaybackButtonPushed(app, event)
            
            cla(app.ProcessedSpectrum, app.OriginSpectrum);

            audiopath = app.PlaybackButton.UserData;
            [y, sr] = audioread(audiopath);

            N = 2048;
            [H, ~] = generateFrequencyResponse(app, N/2);
            phi = 2*pi/N : 2*pi/N : pi;
            phi = phi';
            H_phase = H .* exp(1i*phi);
           
            H_fft = vertcat(H(end), H_phase, conj(H_phase(end-1:-1:1)));
            h = ifftshift(ifft(H_fft, 1024, 'symmetric'));
            [up, ~] = envelope(h, 20, 'peak');
            h_start = find(up > 0.01 * max(up));
            h_start = h_start(1);
            h_end = length(up) - h_start;
            AmplitudeResponse = h(h_start:h_end) .* hamming(h_end- h_start+1, 'periodic')';

            [s1, f1, t1] = stft(sum(y, 2), sr, 'Window', gausswin(2048), 'OverlapLength', 512, 'FFTLength', 2048, 'FrequencyRange', 'onesided');       

            if all(AmplitudeResponse(:)==1)
                audio = y;
                meshPlot(app, s1, f1, t1, 2);
            else
                audio = zeros(2, size(y, 1))';
                for c = 1:size(y, 2)
                    audio(:, c) = conv(y(:, c), h, 'same');
                end
                [s2, f2, t2] = stft(sum(audio, 2), sr, 'Window', gausswin(2048), 'OverlapLength', 512, 'FFTLength', 2048, 'FrequencyRange', 'onesided');
                meshPlot(app, s2, f2, t2, 2);
            end
            app.Lamp.Color = [0.3 0.04 0.5];

            app.ExportAudioButton.UserData = audio;
            
            sound(audio, sr);  

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound
        end

        % Button pushed function: ResponseRecoveryButton
        function ResponseRecoveryButtonPushed(app, event)
            startupFcn(app)
            updatePlot(app)
            app.Lamp.Color = [0.15 0.15 0.15];
        end

        % Button pushed function: ExportAudioButton
        function ExportAudioButtonPushed(app, event)
           
            [file, path] = uiputfile({'*.wav; *.mp3', 'AudioFormat (*.wav,*.mp3)'}, 'defname', 'ExportAudio.wav');
            if isequal(file, 0)
               errordlg("Didn't select any effective file","ERROR")
            else
                audio = app.ExportAudioButton.UserData;
                audiopath = app.PlaybackButton.UserData;

                [~, sr] = audioread(audiopath);
                savepath = [path, file];
                audiowrite(savepath, audio, sr);
            end

        end

        % Value changing function: F1Knob
        function F1KnobValueChanging(app, event)
            num = event.Value;
            app.Lamp.Color = [0.15 0.15 0.15];
            app.F1Knob.Value = num;
            app.F1EditField.Value = num;
            updatePlot(app)
        end

        % Value changing function: F2Knob
        function F2KnobValueChanging(app, event)
            num = event.Value;
            app.Lamp.Color = [0.15 0.15 0.15];
            app.F2Knob.Value = num;
            app.F2EditField.Value = num;
            updatePlot(app)
        end

        % Value changing function: F3Knob
        function F3KnobValueChanging(app, event)
            num = event.Value;
            app.Lamp.Color = [0.15 0.15 0.15];
            app.F3Knob.Value = num;
            app.F3EditField.Value = num;
            updatePlot(app)       
        end

        % Value changing function: F4Knob
        function F4KnobValueChanging(app, event)
            num = event.Value;
            app.Lamp.Color = [0.15 0.15 0.15];
            app.F4Knob.Value = num;
            app.F4EditField.Value = num;
            updatePlot(app)   
        end

        % Value changing function: F5Knob
        function F5KnobValueChanging(app, event)
            num = event.Value;
            app.Lamp.Color = [0.15 0.15 0.15];
            app.F5Knob.Value = num;
            app.F5EditField.Value = num;
            updatePlot(app)      
        end

        % Value changing function: Q2Knob
        function Q2KnobValueChanging(app, event)
            num = event.Value;
            app.Lamp.Color = [0.15 0.15 0.15];
            app.Q2Knob.Value = num;
            app.Q2EditField.Value = num;
            updatePlot(app)     
        end

        % Value changing function: Q3Knob
        function Q3KnobValueChanging(app, event)
            num = event.Value;
            app.Lamp.Color = [0.15 0.15 0.15];
            app.Q3Knob.Value = num;
            app.Q3EditField.Value = num;
            updatePlot(app)     
        end

        % Value changing function: Q4Knob
        function Q4KnobValueChanging(app, event)
            num = event.Value;
            app.Lamp.Color = [0.15 0.15 0.15];
            app.Q4Knob.Value = num;
            app.Q4EditField.Value = num;
            updatePlot(app)   
        end

        % Value changing function: Gain1Knob
        function Gain1KnobValueChanging(app, event)
            num = event.Value;
            app.Lamp.Color = [0.15 0.15 0.15];
            app.Gain1Knob.Value = num;
            app.Gain1EditField.Value = num;
            updatePlot(app)   
        end

        % Value changing function: Gain2Knob
        function Gain2KnobValueChanging(app, event)
            num = event.Value;
            app.Lamp.Color = [0.15 0.15 0.15];
            app.Gain2Knob.Value = num;
            app.Gain2EditField.Value = num;
            updatePlot(app)     
        end

        % Value changing function: Gain3Knob
        function Gain3KnobValueChanging(app, event)
            num = event.Value;
            app.Lamp.Color = [0.15 0.15 0.15];
            app.Gain3Knob.Value = num;
            app.Gain3EditField.Value = num;
            updatePlot(app)       
        end

        % Value changing function: Gain4Knob
        function Gain4KnobValueChanging(app, event)
            num = event.Value;
            app.Lamp.Color = [0.15 0.15 0.15];
            app.Gain4Knob.Value = num;
            app.Gain4EditField.Value = num;
            updatePlot(app)   
        end

        % Value changing function: Gain5Knob
        function Gain5KnobValueChanging(app, event)
            num = event.Value;
            app.Lamp.Color = [0.15 0.15 0.15];
            app.Gain5Knob.Value = num;
            app.Gain5EditField.Value = num;
            updatePlot(app)   
        end

        % Value changed function: F1EditField
        function F1EditFieldValueChanged(app, event)
            f_value = event.Value;
            app.F1Knob.Value = f_value;
            app.Lamp.Color = [0.15 0.15 0.15];
            updatePlot(app)
        end

        % Value changed function: F2EditField
        function F2EditFieldValueChanged(app, event)
            f_value = event.Value;
            app.F2Knob.Value = f_value;
            app.Lamp.Color = [0.15 0.15 0.15];
            updatePlot(app)     
        end

        % Value changed function: F3EditField
        function F3EditFieldValueChanged(app, event)
            f_value = event.Value;
            app.F3Knob.Value = f_value;
            app.Lamp.Color = [0.15 0.15 0.15];
            updatePlot(app)
        end

        % Value changed function: F4EditField
        function F4EditFieldValueChanged(app, event)
            f_value = event.Value;
            app.F4Knob.Value = f_value;
            app.Lamp.Color = [0.15 0.15 0.15];
            updatePlot(app)
        end

        % Value changed function: F5EditField
        function F5EditFieldValueChanged(app, event)
            f_value = event.Value;
            app.F5Knob.Value = f_value;
            app.Lamp.Color = [0.15 0.15 0.15];
            updatePlot(app)
        end

        % Value changed function: Gain1EditField
        function Gain1EditFieldValueChanged(app, event)
            f_value = event.Value;
            app.Gain1Knob.Value = f_value;
            app.Lamp.Color = [0.15 0.15 0.15];
            updatePlot(app)
        end

        % Value changed function: Gain2EditField
        function Gain2EditFieldValueChanged(app, event)
            f_value = event.Value;
            app.Gain2Knob.Value = f_value;
            app.Lamp.Color = [0.15 0.15 0.15];
            updatePlot(app)
        end

        % Value changed function: Gain3EditField
        function Gain3EditFieldValueChanged(app, event)
            f_value = event.Value;
            app.Gain3Knob.Value = f_value;
            app.Lamp.Color = [0.15 0.15 0.15];
            updatePlot(app)
        end

        % Value changed function: Gain4EditField
        function Gain4EditFieldValueChanged(app, event)
            f_value = event.Value;
            app.Gain4Knob.Value = f_value;
            app.Lamp.Color = [0.15 0.15 0.15];
            updatePlot(app)
        end

        % Value changed function: Gain5EditField
        function Gain5EditFieldValueChanged(app, event)
            f_value = event.Value;
            app.Gain5Knob.Value = f_value;
            app.Lamp.Color = [0.15 0.15 0.15];
            updatePlot(app)
        end

        % Value changed function: Q2EditField
        function Q2EditFieldValueChanged(app, event)
            f_value = event.Value;
            app.Q2Knob.Value = f_value;
            app.Lamp.Color = [0.15 0.15 0.15];
            updatePlot(app)
        end

        % Value changed function: Q3EditField
        function Q3EditFieldValueChanged(app, event)
            f_value = event.Value;
            app.Q3Knob.Value = f_value;
            app.Lamp.Color = [0.15 0.15 0.15];
            updatePlot(app)   
        end

        % Value changed function: Q4EditField
        function Q4EditFieldValueChanged(app, event)
            f_value = event.Value;
            app.Q4Knob.Value = f_value;
            app.Lamp.Color = [0.15 0.15 0.15];
            updatePlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UpPanel = uipanel(app.UIFigure);
            app.EQPlot = uiaxes(app.UpPanel);
            title(app.EQPlot, 'Amplitude Frequency Response')
            xlabel(app.EQPlot, 'Frequency(Hz)')
            ylabel(app.EQPlot, 'Gain(dB)')
            zlabel(app.EQPlot, 'Z')
            app.EQPlot.XLim = [0 20000];
            app.EQPlot.YLim = [-18 18];
            app.EQPlot.XTick = [0 20 50 100 200 500 1000 2000 5000 10000 20000];
            app.EQPlot.XScale = 'log';
            app.EQPlot.XTickLabel = {'0'; '20'; '50'; '100'; '200'; '500'; '1k'; '2k'; '5k'; '10k'; '20k'};
            app.EQPlot.YAxisLocation = 'right';
            app.EQPlot.YTick = [-18 -12 -6 -3 0 3 6 9 12 18];
            app.EQPlot.YTickLabel = {'-18'; '-12'; '-6'; '-3'; '0'; '3'; '6'; '9'; '12'; '18'};
            app.EQPlot.AlphaScale = 'log';
            app.Panel = uipanel(app.UpPanel);
            app.SelectAudioFileButton = uibutton(app.Panel, 'push');
            app.SelectAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAudioFileButtonPushed, true);
            app.SelectAudioFileButton.Text = 'Select Audio File';
            app.PlaybackButton = uibutton(app.Panel, 'push');
            app.PlaybackButton.ButtonPushedFcn = createCallbackFcn(app, @PlaybackButtonPushed, true);
            app.PlaybackButton.Text = 'Playback';
            app.StopButton = uibutton(app.Panel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.ResponseRecoveryButton = uibutton(app.Panel, 'push');
            app.ResponseRecoveryButton.ButtonPushedFcn = createCallbackFcn(app, @ResponseRecoveryButtonPushed, true);
            app.ResponseRecoveryButton.Text = {'Response Recovery'; ''};
            app.ExportAudioButton = uibutton(app.Panel, 'push');
            app.ExportAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAudioButtonPushed, true);
            app.ExportAudioButton.Text = {'Export Audio'; ''};
            app.FilenameEditField = uieditfield(app.Panel, 'text');
            app.FilenameEditField.Value = '<...No File...>';
            app.PresetsDropDown = uidropdown(app.UpPanel);
            app.PresetsDropDown.Items = {'<-Select Presets->', 'Low Cut', 'High Cut', 'Band Pass', 'Band Stop', 'Drums', 'Phone', 'Vocal', 'Tilt Shelf', 'Front Enhance(Shu-Nung)', 'Above Enhance(Shu-Nung)', 'Front Enhance(Song)'};
            app.PresetsDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetsDropDownValueChanged, true);
            app.PresetsDropDown.Value = '<-Select Presets->';
            app.DownPanel = uipanel(app.UIFigure);
            app.FrequencyHzLabel = uilabel(app.DownPanel);
            app.FrequencyHzLabel.Text = {'Frequency'; '      (Hz)'};
            app.GaindBLabel = uilabel(app.DownPanel);
            app.GaindBLabel.Text = 'Gain(dB)';
            app.QLabel = uilabel(app.DownPanel);
            app.QLabel.Text = 'Q';
            app.KnobPanel = uipanel(app.DownPanel);
            app.Gain1Knob = uiknob(app.KnobPanel, 'continuous');
            app.Gain1Knob.Limits = [-30 18];
            app.Gain1Knob.MajorTicks = [-30 -21 -12 -6 0 9 18];
            app.Gain1Knob.MajorTickLabels = {'-30', '-21', '-12', '-6', '0', '9', '18'};
            app.Gain1Knob.ValueChangingFcn = createCallbackFcn(app, @Gain1KnobValueChanging, true);
            app.Gain2Knob = uiknob(app.KnobPanel, 'continuous');
            app.Gain2Knob.Limits = [-18 18];
            app.Gain2Knob.MajorTicks = [-18 -12 -6 0 3 6 9 12 18];
            app.Gain2Knob.MajorTickLabels = {'-18', '-12', '-6', '0', '3', '6', '9', '12', '18'};
            app.Gain2Knob.ValueChangingFcn = createCallbackFcn(app, @Gain2KnobValueChanging, true);
            app.Gain2Knob.MinorTicks = [-18 -16.8 -15.6 -14.4 -13.2 -12 -10.8 -9.6 -8.4 -7.2 -6 -4.8 -3.6 -2.4 -1.2 0 1.2 2.4 3.6 4.8 6 7.2 8.4 9.6 10.8 12 13.2 14.4 15.6 16.8 18];
            app.Gain3Knob = uiknob(app.KnobPanel, 'continuous');
            app.Gain3Knob.Limits = [-18 18];
            app.Gain3Knob.MajorTicks = [-18 -12 -6 0 3 6 9 12 18];
            app.Gain3Knob.ValueChangingFcn = createCallbackFcn(app, @Gain3KnobValueChanging, true);
            app.Gain4Knob = uiknob(app.KnobPanel, 'continuous');
            app.Gain4Knob.Limits = [-18 18];
            app.Gain4Knob.MajorTicks = [-18 -12 -6 0 3 6 9 12 18];
            app.Gain4Knob.ValueChangingFcn = createCallbackFcn(app, @Gain4KnobValueChanging, true);
            app.Gain5Knob = uiknob(app.KnobPanel, 'continuous');
            app.Gain5Knob.Limits = [-30 18];
            app.Gain5Knob.MajorTicks = [-30 -21 -12 -6 0 9 18];
            app.Gain5Knob.ValueChangingFcn = createCallbackFcn(app, @Gain5KnobValueChanging, true);
            app.Q2Knob = uiknob(app.KnobPanel, 'continuous');
            app.Q2Knob.Limits = [0.01 30];
            app.Q2Knob.MajorTicks = [0.01 3 6 9 12 15 20 30];
            app.Q2Knob.MajorTickLabels = {'0', '3', '6', '9', '12', '15', '20', '30'};
            app.Q2Knob.ValueChangingFcn = createCallbackFcn(app, @Q2KnobValueChanging, true);
            app.Q2Knob.Value = 0.01;
            app.Q3Knob = uiknob(app.KnobPanel, 'continuous');
            app.Q3Knob.Limits = [0.01 30];
            app.Q3Knob.MajorTicks = [0.01 3 6 9 12 15 20 30];
            app.Q3Knob.MajorTickLabels = {'0', '3', '6', '9', '12', '15', '20', '30'};
            app.Q3Knob.ValueChangingFcn = createCallbackFcn(app, @Q3KnobValueChanging, true);
            app.Q3Knob.MinorTicks = [0.01 1.01 2.01 3.01 4.01 5.01 6.01 7.01 8.01 9.01 10.01 11.01 12.01 13.01 14.01 15.01 16.01 17.01 18.01 19.01 20.01 21.01 22.01 23.01 24.01 25.01 26.01 27.01 28.01 29.01 30];
            app.Q3Knob.Value = 0.01;
            app.Q4Knob = uiknob(app.KnobPanel, 'continuous');
            app.Q4Knob.Limits = [0.01 30];
            app.Q4Knob.MajorTicks = [0.01 3 6 9 12 15 20 30];
            app.Q4Knob.MajorTickLabels = {'0', '3', '6', '9', '12', '15', '20', '30'};
            app.Q4Knob.ValueChangingFcn = createCallbackFcn(app, @Q4KnobValueChanging, true);
            app.Q4Knob.Value = 0.01;
            app.F1Knob = uiknob(app.KnobPanel, 'continuous');
            app.F1Knob.Limits = [0 15000];
            app.F1Knob.MajorTicks = [0 3000 6000 9000 12000 15000];
            app.F1Knob.MajorTickLabels = {'0', '3k', '6k', '9k', '12k', '15k'};
            app.F1Knob.ValueChangingFcn = createCallbackFcn(app, @F1KnobValueChanging, true);
            app.F1Knob.MinorTicks = [0 500 1000 1500 2000 2500 3000 3500 4000 4500 5000 5500 6000 6500 7000 7500 8000 8500 9000 9500 10000 10500 11000 11500 12000 12500 13000 13500 14000 14500 15000];
            app.F1Knob.Value = 50;
            app.F2Knob = uiknob(app.KnobPanel, 'continuous');
            app.F2Knob.Limits = [0 15000];
            app.F2Knob.MajorTicks = [0 3000 6000 9000 12000 15000];
            app.F2Knob.MajorTickLabels = {'0', '3k', '6k', '9k', '12k', '15k'};
            app.F2Knob.ValueChangingFcn = createCallbackFcn(app, @F2KnobValueChanging, true);
            app.F2Knob.Value = 100;
            app.F3Knob = uiknob(app.KnobPanel, 'continuous');
            app.F3Knob.Limits = [0 15000];
            app.F3Knob.MajorTicks = [0 3000 6000 9000 12000 15000];
            app.F3Knob.MajorTickLabels = {'0', '3k', '6k', '9k', '12k', '15k'};
            app.F3Knob.ValueChangingFcn = createCallbackFcn(app, @F3KnobValueChanging, true);
            app.F3Knob.Value = 1000;
            app.F4Knob = uiknob(app.KnobPanel, 'continuous');
            app.F4Knob.Limits = [0 15000];
            app.F4Knob.MajorTicks = [0 3000 6000 9000 12000 15000];
            app.F4Knob.MajorTickLabels = {'0', '3k', '6k', '9k', '12k', '15k'};
            app.F4Knob.ValueChangingFcn = createCallbackFcn(app, @F4KnobValueChanging, true);
            app.F4Knob.Value = 8000;
            app.F5Knob = uiknob(app.KnobPanel, 'continuous');
            app.F5Knob.Limits = [0 18000];
            app.F5Knob.MajorTicks = [0 3000 6000 9000 12000 15000 18000];
            app.F5Knob.MajorTickLabels = {'0', '3k', '6k', '9k', '12k', '15k', '18k'};
            app.F5Knob.ValueChangingFcn = createCallbackFcn(app, @F5KnobValueChanging, true);
            app.F5Knob.Value = 8000;
            app.LowShelfLabel = uilabel(app.KnobPanel);
            app.LowShelfLabel.Text = 'Low Shelf';
            app.PeakNotchLabel = uilabel(app.KnobPanel);
            app.PeakNotchLabel.Text = 'Peak/Notch';
            app.HighShelfLabel = uilabel(app.KnobPanel);
            app.HighShelfLabel.Text = 'High Shelf';
            app.F1EditField = uieditfield(app.KnobPanel, 'numeric');
            app.F1EditField.Limits = [0 15000];
            app.F1EditField.ValueDisplayFormat = '%.0f';
            app.F1EditField.ValueChangedFcn = createCallbackFcn(app, @F1EditFieldValueChanged, true);
            app.F1EditField.Value = 50;
            app.F2EditField = uieditfield(app.KnobPanel, 'numeric');
            app.F2EditField.Limits = [0 15000];
            app.F2EditField.ValueDisplayFormat = '%.0f';
            app.F2EditField.ValueChangedFcn = createCallbackFcn(app, @F2EditFieldValueChanged, true);
            app.F2EditField.Value = 100;
            app.F3EditField = uieditfield(app.KnobPanel, 'numeric');
            app.F3EditField.Limits = [0 15000];
            app.F3EditField.ValueDisplayFormat = '%.0f';
            app.F3EditField.ValueChangedFcn = createCallbackFcn(app, @F3EditFieldValueChanged, true);
            app.F3EditField.Value = 1000;
            app.F4EditField = uieditfield(app.KnobPanel, 'numeric');
            app.F4EditField.Limits = [0 15000];
            app.F4EditField.ValueDisplayFormat = '%.0f';
            app.F4EditField.ValueChangedFcn = createCallbackFcn(app, @F4EditFieldValueChanged, true);
            app.F4EditField.Value = 8000;
            app.F5EditField = uieditfield(app.KnobPanel, 'numeric');
            app.F5EditField.Limits = [0 20000];
            app.F5EditField.ValueDisplayFormat = '%.0f';
            app.F5EditField.ValueChangedFcn = createCallbackFcn(app, @F5EditFieldValueChanged, true);
            app.F5EditField.Value = 10000;
            app.Gain1EditField = uieditfield(app.KnobPanel, 'numeric');
            app.Gain1EditField.Limits = [-30 18];
            app.Gain1EditField.ValueDisplayFormat = '%.1f';
            app.Gain1EditField.ValueChangedFcn = createCallbackFcn(app, @Gain1EditFieldValueChanged, true);
            app.Gain2EditField = uieditfield(app.KnobPanel, 'numeric');
            app.Gain2EditField.Limits = [-18 18];
            app.Gain2EditField.ValueDisplayFormat = '%.1f';
            app.Gain2EditField.ValueChangedFcn = createCallbackFcn(app, @Gain2EditFieldValueChanged, true);
            app.Gain3EditField = uieditfield(app.KnobPanel, 'numeric');
            app.Gain3EditField.Limits = [-18 18];
            app.Gain3EditField.ValueDisplayFormat = '%.1f';
            app.Gain3EditField.ValueChangedFcn = createCallbackFcn(app, @Gain3EditFieldValueChanged, true);
            app.Gain4EditField = uieditfield(app.KnobPanel, 'numeric');
            app.Gain4EditField.Limits = [-18 18];
            app.Gain4EditField.ValueDisplayFormat = '%.1f';
            app.Gain4EditField.ValueChangedFcn = createCallbackFcn(app, @Gain4EditFieldValueChanged, true);
            app.Gain5EditField = uieditfield(app.KnobPanel, 'numeric');
            app.Gain5EditField.Limits = [-30 18];
            app.Gain5EditField.ValueDisplayFormat = '%.1f';
            app.Gain5EditField.ValueChangedFcn = createCallbackFcn(app, @Gain5EditFieldValueChanged, true);
            app.Q2EditField = uieditfield(app.KnobPanel, 'numeric');
            app.Q2EditField.Limits = [0.01 30];
            app.Q2EditField.ValueDisplayFormat = '%.1f';
            app.Q2EditField.ValueChangedFcn = createCallbackFcn(app, @Q2EditFieldValueChanged, true);
            app.Q2EditField.Value = 0.01;
            app.Q3EditField = uieditfield(app.KnobPanel, 'numeric');
            app.Q3EditField.Limits = [0.01 30];
            app.Q3EditField.ValueDisplayFormat = '%.1f';
            app.Q3EditField.ValueChangedFcn = createCallbackFcn(app, @Q3EditFieldValueChanged, true);
            app.Q3EditField.Value = 0.01;
            app.Q4EditField = uieditfield(app.KnobPanel, 'numeric');
            app.Q4EditField.Limits = [0.01 30];
            app.Q4EditField.ValueDisplayFormat = '%.1f';
            app.Q4EditField.ValueChangedFcn = createCallbackFcn(app, @Q4EditFieldValueChanged, true);
            app.Q4EditField.Value = 0.01;
            app.Lamp = uilamp(app.DownPanel);
            app.SpectrumPanel = uipanel(app.DownPanel);
            app.OriginSpectrum = uiaxes(app.SpectrumPanel);
            app.OriginSpectrum.XTick = [];
            app.OriginSpectrum.XTickLabel = '';
            app.OriginSpectrum.YTick = [];
            app.OriginSpectrum.YTickLabel = '';
            app.ProcessedSpectrum = uiaxes(app.SpectrumPanel);
            app.ProcessedSpectrum.XTick = [];
            app.ProcessedSpectrum.YTick = [];
            app.ProcessedSpectrum.YTickLabel = '';
            app.DesignedByQiuLabel = uilabel(app.DownPanel);
            app.DesignedByQiuLabel.Text = 'Designed By Qiu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Equalizer_purple

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end